classdef FinalProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SmoothSwitch           matlab.ui.control.Switch
        ClearnoiseSwitchLabel  matlab.ui.control.Label
        ReportButton           matlab.ui.control.Button
        Panel                  matlab.ui.container.Panel
        SummaryMinMaxLabel     matlab.ui.control.Label
        SummaryStatusLabel     matlab.ui.control.Label
        SummaryHrLabel         matlab.ui.control.Label
        SummaryBeatsLabel      matlab.ui.control.Label
        ExportButton           matlab.ui.control.Button
        TachoButton            matlab.ui.control.Button
        PeaksCountLabel        matlab.ui.control.Label
        PeaksButton            matlab.ui.control.Button
        ThresholdEditField     matlab.ui.control.NumericEditField
        ThresholdLabel         matlab.ui.control.Label
        LoadButton             matlab.ui.control.Button
        HistAxes               matlab.ui.control.UIAxes
        TachoAxes              matlab.ui.control.UIAxes
        EcgAxes                matlab.ui.control.UIAxes
    end

    properties (Access = private)
        ecg_signal %וקטור לשמירת אות הECG 
        peak_indices %לשמירת האינדקסים של הפיקים
        r_intervals_ms % מרווחי הזמנים בין כל שני פיקים- RR
        mean_hr % דופק ממוצע
        raw_ecg_signal % משתנה נוסף לשמירת האות (לפני החלקה)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
    % טעינה של הקובץ
    [file, path] = uigetfile('*.mat', 'בחר קובץ נתוני ECG'); % פתיחת חלון בחירה בשם:
    if isequal(file, 0), return; end % אם המשתמש לחץ ביטול, מונע מהתוכנית לקרוס
    
    % בדיקת תקינות הקובץ
    [~, ~, fileExt] = fileparts(file); %הפונקציה מחזירה שם קובץ נתיב וסיומת, רק סיומת רלוונטית
    if ~strcmpi(fileExt, '.mat') % אם הסיומת אינה .mat
        % הצגת הודעת שגיאה למשתמש
        uialert(app.UIFigure, 'הקובץ שנבחר אינו קובץ MATLAB תקין! אנא בחר קובץ עם סיומת .mat בלבד.', ...
            'שגיאה בסוג הקובץ', 'Icon', 'error'); % הצגת הודעת שגיאה בחלון האפליקציה 
        
        % עדכון הלייבל בפאנל
        app.SummaryStatusLabel.Text = 'סטטוס מערכת: שגיאה! נסיון לטעינת קובץ שאינו .mat';
        return; % עצירת הפונקציה כאן ומניעת המשך הריצה (מונע קריסה)
    end
    
    % טעינה ושמירת נתונים
    data = load(fullfile(path, file));%יצירת מחרוזת של נתיב ושם הקובץ, קריאת המידע מהקובץ ושמירה במבנה דאטה כדי למנוע דריסת משתנים
    signal = data.ECGsig;
    app.ecg_signal = signal - mean(signal); %מרכוז האות סביב האפס 
    app.raw_ecg_signal = app.ecg_signal;% העתק של האות הממורכז למתי שהמשתמש ירצה להחליק את הגרף 
    
    Fs = 128; 
    N = length(app.ecg_signal); %אורך הוקטור=כמות הדגימות
    t = (0:N-1) / Fs; % יצירת וקטור הזמן בשניות= 20 שניות
    
    plot(app.EcgAxes, t, app.ecg_signal, 'k'); 
    xlabel(app.EcgAxes, 'Time (s)');
    ylabel(app.EcgAxes, 'ECG Signal Amplitude');
    title(app.EcgAxes, 'ECG Signal over Time');
    grid(app.EcgAxes, 'on');

    % ניקוי גרפים ולייבלים של קובץ קודם
    cla(app.TachoAxes);
    cla(app.HistAxes);
    app.PeaksCountLabel.Text = 'Number of Peaks';
    app.SummaryBeatsLabel.Text = ':כמות פעימות בבדיקה';
    app.SummaryHrLabel.Text = ':דופק ממוצע';
    app.SummaryMinMaxLabel.Text = ':דופק מינימלי/ מקסימלי';
    app.TachoButton.Enable = 'off';
    app.ExportButton.Enable = 'off';
    app.ReportButton.Enable = 'off';
    
    app.ThresholdEditField.Value = 3 * std(app.ecg_signal);% שלושה פעמים סטיית התקן
    app.SummaryStatusLabel.Text = 'סטטוס מערכת: האות נטען בהצלחה! נא לחשב פיקים.';
    app.SmoothSwitch.Enable = 'on'; 
        end

        % Button pushed function: PeaksButton
        function PeaksButtonPushed(app, event)
    % הגדרת משתנים לפונקציה
    Fs = 128;
    N = length(app.ecg_signal);
    t = (0:N-1) / Fs;
    threshold = app.ThresholdEditField.Value; 
    min_dist = 0.4 * Fs; % הגדרת כמות דגימות שמדלגים עליהם בין כל פיק

    % חישוב הפיקים בהתאם לערך סף ומרחק מינימלי בין דגימות
    %שמירת אמפליטודות ואינדקסים במשתנים
    [pks, app.peak_indices] = findpeaks(app.ecg_signal, 'MinPeakHeight', threshold, 'MinPeakDistance', min_dist);
    
    % פנייה למערך הזמן בעזרת מערך האינדקסים של פיקים, שמירת הזמנים בהם יש
    % דופק במשתנה 
    time_locs = t(app.peak_indices); 

    plot(app.EcgAxes, t, app.ecg_signal, 'k');% הצגת הגרף
    
    % נעילת הגרף וציור עיגולים אדומים בפיקים
    hold(app.EcgAxes, 'on');
    plot(app.EcgAxes, time_locs, pks, 'ro', 'MarkerFaceColor', 'r'); 
    
    hold(app.EcgAxes, 'off');% שחרור הגרף במידה ונטען קובץ מחדש או נשנה ערך סף
    
    % החזרת התוויות לגרף שנמחקו אחרי הפלוט
    xlabel(app.EcgAxes, 'Time (s)');
    ylabel(app.EcgAxes, 'ECG Signal Amplitude');
    title(app.EcgAxes, 'ECG Signal over Time with Detected R-Peaks');
    grid(app.EcgAxes, 'on');
    
 
    num_peaks = length(pks); % ספירת כמות הפיקים
    app.PeaksCountLabel.Text = ['כמות פיקים: ', num2str(num_peaks)]; %עדכון לייבל
    app.TachoButton.Enable = 'on'; %שחרור כפתור טכוגרמה
    %עדכון סטטוס בפאנל
    app.SummaryStatusLabel.Text = 'סטטוס מערכת: הפיקים חושבו. כעת לחץ על כפתור חישוב הטכוגרמה';
        end

        % Button pushed function: TachoButton
        function TachoButtonPushed(app, event)
    Fs = 128;
    
    intervals_samples = diff(app.peak_indices); %חישוב מרווחים בין הפיקים, הפונקציה דיף מחסרת כל 2 איברים עוקבים
    %חילוק המרווחים בתדירות דגימה=המרה מדגימות לשניות ואז המרה למילישניות
    intervals_sec = intervals_samples / Fs; 
    app.r_intervals_ms = intervals_sec * 1000; 
    
    beats_axis = 1:length(app.r_intervals_ms);%יצירת אינדקסים לציר איקס של טכוגרמה(מספר פעימה)
    %שרטוט גרף הטכוגרמה, ויצירת כותרות מתאימות ורשת
    plot(app.TachoAxes, beats_axis, app.r_intervals_ms, '-k', 'LineWidth', 1.2);
    title(app.TachoAxes, 'Tachogram');
    xlabel(app.TachoAxes, 'Beat [#]');
    ylabel(app.TachoAxes, 'Interval [ms]');
    grid(app.TachoAxes, 'on');
    
    mean_rr_sec = mean(intervals_sec); % זמן ממוצע של פעימת לב אחת (בשניות)
    app.mean_hr = 60 / mean_rr_sec;    % דופק לדקה= 60 שניות חלקי ממוצע זמן לפעימה אחת
   
    % שרטוט גרף ההיסטוגרמה בעזרת פונקציה מוכנה 
    histogram(app.HistAxes, app.r_intervals_ms, 'FaceColor', 'k');%מקבלת את המערך מרווחים ומחלקת לטווחים, סופרת כמה פעמים הופיע מרווח בכל טווח
    
    % יצירת הכותרת הדינמית שנדרשה בפרויקט ושמירתה במשתנה
    title_str = sprintf('The mean heart rate of the subject is %.1f beats/min', app.mean_hr);
    title(app.HistAxes, title_str);%יישום המחרוזת לכותרת
    % עדכון כותרות צירים ורשת
    xlabel(app.HistAxes, 'Time -> Ms');
    ylabel(app.HistAxes, 'Number of Occurrences');
    grid(app.HistAxes, 'on');
    
    app.ExportButton.Enable = 'on';%שחרור כפתור יצוא לאקסל
    % עדכון לייבלים בפאנל
    app.SummaryBeatsLabel.Text = ['כמות פעימות בבדיקה: ', num2str(length(app.r_intervals_ms))];
    app.SummaryHrLabel.Text = sprintf('דופק ממוצע (BPM): %.1f', app.mean_hr);  

    % חלוקת 60 שניות במרוווח בין כל פעימה=קצב דופק רגעי לכל פעימה
    instant_hr = 60 ./ intervals_sec; 
    
    % שליפת ערכי המינימום והמקסימום של הדופק בנקודה בודדת
    min_hr = min(instant_hr);
    max_hr = max(instant_hr);
    
    % יעדכון הלייבלים בפאנל
    app.SummaryMinMaxLabel.Text = sprintf('דופק (Min/Max): %.1f / %.1f BPM', min_hr, max_hr);
    app.SummaryStatusLabel.Text = 'סטטוס מערכת: כל החישובים והגרפים מעודכנים ומוכנים!';

    app.ReportButton.Enable = 'on';% שחרור כפתור יצוא כתמונה
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            % המשתמש שינה את הסף - נועלים את כפתור הטכוגרמה ודורשים חישוב פיקים מחדש
             app.TachoButton.Enable = 'off';
            app.PeaksCountLabel.Text = 'עודכן סף, חשב פיקים מחדש';
            % ניקוי גרפים ולייבלים בעקבות שינוי הסף
            cla(app.TachoAxes);
            cla(app.HistAxes);
            app.SummaryBeatsLabel.Text = ':כמות פעימות בבדיקה';
            app.SummaryHrLabel.Text = ':דופק ממוצע';
            app.SummaryMinMaxLabel.Text = ':דופק מינימלי/ מקסימלי';
            app.ExportButton.Enable = 'off';
            app.ReportButton.Enable = 'off';
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
    % פתיחת חלון לשמירת קובץ חדש עם הכותרת הרשומה, מחייב לשמור כקובץ אקסל
    %הפונקציה מחזירה את שם הקובץ ואת הנתיב
    [file, path] = uiputfile('*.xlsx', 'שמור נתוני טכוגרמה כקובץ Excel');
    
    %אם המשתמש לחץ ביטול הקובץ מקבל ערך אפס, אם זה קרה עוצרים ריצה כדי למנוע קריסה
    if isequal(file, 0)
        return; 
    end
    
    %סידור הנתונים לעמודות כדי לייצא לאקסל
    % האופורטור של נקודותיים וגרש הופכים לוקטורים אנכיים
    Beats = (1:length(app.r_intervals_ms))'; % וקטור אנכי של מספרי פעימות
    Intervals = app.r_intervals_ms(:);       % וקטור אנכי של מרווחים במילישניות
    
    % יצירת טבלה מהנתונים בעזרת פונקציה מובנת
    dataTable = table(Beats, Intervals);%מצמידה את הוקטורים האנכיים ויוצרת טבלה
    
    fullFilePath = fullfile(path, file);% מחברת את הנתיב עם שם הקובץ ויוצרת מחרוזת מלאה של הנתיב
    writetable(dataTable, fullFilePath);%מקבלת את הטבלה ואת הנתיב ושומרת בקובץ אקסל
    
    % הקפצת הודעה באפליקציה של הצלחת השמירה
    msg = sprintf('הנתונים נשמרו בהצלחה!\nהדופק הממוצע של הנבדק: %.1f BPM', app.mean_hr);
    uialert(app.UIFigure, msg, 'ייצוא הושלם', 'Icon', 'success');
        end

        % Button pushed function: ReportButton
        function ReportButtonPushed(app, event)
    % פתיחת חלון לשמירת הקובץ,מאלץ פורמט של תמונה ומציג את הכותרת של החלון
    [file, path] = uiputfile('*.png', 'שמור דוח גרפי כחלון תמונה');
    
    %אם המשתמש לחץ ביטול הקובץ מקבל ערך אפס, אם זה קרה עוצרים ריצה כדי למנוע קריסה
    if isequal(file, 0)
        return; 
    end
    
    % עדכון סטטוס בפאנל
    app.SummaryStatusLabel.Text = 'סטטוס מערכת: מייצר דוח תמונה...';
    %המחשב מתעדף בימוע פקודות לוגיות בזכרון ודוחה ריענון ויזאולי לסוף הריצה
    drawnow; % גורמת למחשב לעשות עדכון ויזואלי באפליקציה לפני שישמור את הצילום 
   
    fullPath = fullfile(path, file);%חיבור הנתיב ושם הקובץ למחרוזת נתיב מלאה
    exportapp(app.UIFigure, fullPath);%פונקציה מובנית שממירה מראה ויזואלי למערך פיקסלים ואז שומרת כתמונה
    
    % עדכון הלייבל בפאנל והקפצת הודעת הצלחה למשתמש
    app.SummaryStatusLabel.Text = 'סטטוס מערכת: דוח תמונה נוצר ונשמר בהצלחה!';
    uialert(app.UIFigure, 'דוח התמונה (צילום הממשק) נשמר בהצלחה בתיקייה שנבחרה!', ...
            'הדוח מוכן', 'Icon', 'success');
        end

        % Value changed function: SmoothSwitch
        function SmoothSwitchValueChanged(app, event)
    value = app.SmoothSwitch.Value;%בודקת את המצב הנוכחי של המתג ושומרת כמחרוזת
    if strcmp(value, 'On')%בדיקת המצב הנוכחי של המתג וביצוע פעולה בהתאם
        % שימוש בפונקציה מובנית של מסנן דיגיטלי להחלקת האוץ
        % מקבלת את אות הגיבוי הגולמי, עושה ממוצע נע לכל חלון ברוחב 5 דגימות
        app.ecg_signal = smoothdata(app.raw_ecg_signal, 'movmean', 5);
        %עדכון לייבל בפאנל
        app.SummaryStatusLabel.Text = 'סטטוס מערכת: האות סונן והוחלק. חובה לחשב פיקים מחדש!';
    else %במקרה שהמתג על מצב כבוי משחזרים את האות המקורי בעזרת הגיבוי
        app.ecg_signal = app.raw_ecg_signal;% פשוט דורסים עם האות המקורי
        %עדכון לייבל בפאנל
        app.SummaryStatusLabel.Text = 'סטטוס מערכת: סינון בוטל. חובה לחשב פיקים מחדש!';
    end
    
    % ציור מחדש של האות על הגרף
    Fs = 128;
    N = length(app.ecg_signal);
    t = (0:N-1) / Fs; %יצירת וקטור שניות
    %שרטוט האות המעודכן (בהתאם למצב המתג),לא עשינו "הולד און" לכן הפקודה
    %מנקה את מה שהיה קודם בגרף (כולל הנקודות האדומות של הפיקים) ויוצג אות
    %נקי חדש
    plot(app.EcgAxes, t, app.ecg_signal, 'k');
    
    % החזרת תוויות כותרת ורשת לגרף
    xlabel(app.EcgAxes, 'Time (s)');
    ylabel(app.EcgAxes, 'ECG Signal Amplitude');
    title(app.EcgAxes, 'ECG Signal over Time');
    grid(app.EcgAxes, 'on');

    % ניקוי גרפי המשך ולייבלים בעקבות שינוי מצב הסינון
    cla(app.TachoAxes);
    cla(app.HistAxes);
    app.PeaksCountLabel.Text = 'האות השתנה, חשב פיקים מחדש';
    app.SummaryBeatsLabel.Text = ':כמות פעימות בבדיקה';
    app.SummaryHrLabel.Text = ':דופק ממוצע';
    app.SummaryMinMaxLabel.Text = ':דופק מינימלי/ מקסימלי';
    
    % נעילת הכפתורים של טכוגרמה וייצא לאקסל/תמונה
    %האות השתנה ולכן השתנו גם מיקומי הפיקים, נועלים את הכפתורים כדי לאלץ את
    %המשתמש מחויב ללחוץ על פיינד פיקס והנתונים מתעדכנים בהתאם למצב נוכחי
    app.TachoButton.Enable = 'off';
    app.ExportButton.Enable = 'off';
    app.ReportButton.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EcgAxes = uiaxes(app.UIFigure);
            title(app.EcgAxes, 'ECG SIGNAL')
            xlabel(app.EcgAxes, 'Time [s]')
            ylabel(app.EcgAxes, 'mV')
            zlabel(app.EcgAxes, 'Z')
            app.TachoAxes = uiaxes(app.UIFigure);
            title(app.TachoAxes, 'Tachogram')
            xlabel(app.TachoAxes, 'Beats')
            ylabel(app.TachoAxes, '[ms]')
            zlabel(app.TachoAxes, 'Z')
            app.HistAxes = uiaxes(app.UIFigure);
            title(app.HistAxes, 'Histogram')
            xlabel(app.HistAxes, 'Time-ms')
            ylabel(app.HistAxes, 'Number of Occurrences')
            zlabel(app.HistAxes, 'Z')
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load ECG file';
            app.ThresholdLabel = uilabel(app.UIFigure);
            app.ThresholdLabel.Text = 'Threshold:';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 5;
            app.PeaksButton = uibutton(app.UIFigure, 'push');
            app.PeaksButton.ButtonPushedFcn = createCallbackFcn(app, @PeaksButtonPushed, true);
            app.PeaksButton.Text = 'Find peaks';
            app.PeaksCountLabel = uilabel(app.UIFigure);
            app.PeaksCountLabel.Text = 'Number of Peaks';
            app.TachoButton = uibutton(app.UIFigure, 'push');
            app.TachoButton.ButtonPushedFcn = createCallbackFcn(app, @TachoButtonPushed, true);
            app.TachoButton.Enable = 'off';
            app.TachoButton.Text = 'Calculate tachogram';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export to Excel';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'סיכום נתוני בדיקה';
            app.SummaryBeatsLabel = uilabel(app.Panel);
            app.SummaryBeatsLabel.Text = ':כמות פעימות בבדיקה';
            app.SummaryHrLabel = uilabel(app.Panel);
            app.SummaryHrLabel.Text = ':דופק ממוצע';
            app.SummaryStatusLabel = uilabel(app.Panel);
            app.SummaryStatusLabel.Text = 'סטטוס מערכת: ממתין לטעינת קובץ נתונים';
            app.SummaryMinMaxLabel = uilabel(app.Panel);
            app.SummaryMinMaxLabel.Text = ':דופק מינימלי/ מקסימלי';
            app.ReportButton = uibutton(app.UIFigure, 'push');
            app.ReportButton.ButtonPushedFcn = createCallbackFcn(app, @ReportButtonPushed, true);
            app.ReportButton.Enable = 'off';
            app.ReportButton.Text = 'Export as Image';
            app.ClearnoiseSwitchLabel = uilabel(app.UIFigure);
            app.ClearnoiseSwitchLabel.Enable = 'off';
            app.ClearnoiseSwitchLabel.Text = 'Clear noise';
            app.SmoothSwitch = uiswitch(app.UIFigure, 'slider');
            app.SmoothSwitch.ValueChangedFcn = createCallbackFcn(app, @SmoothSwitchValueChanged, true);
            app.SmoothSwitch.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end